classdef ImageRegistrator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageRegistrationPanel     matlab.ui.container.Panel
        DropDown                   matlab.ui.control.DropDown
        LoadTransformMatrixButton  matlab.ui.control.Button
        RegistratorButton          matlab.ui.control.Button
        ZstepsizeumEditField       matlab.ui.control.NumericEditField
        ZstepsizeumEditFieldLabel  matlab.ui.control.Label
        PixelsizeumEditField       matlab.ui.control.NumericEditField
        PixelsizeumLabel           matlab.ui.control.Label
        ImagingModeDropDown        matlab.ui.control.DropDown
        ImagingModeDropDownLabel   matlab.ui.control.Label
        DimensionDropDown          matlab.ui.control.DropDown
        DimensionDropDownLabel     matlab.ui.control.Label
        DefineOutputPathButton     matlab.ui.control.Button
        DefineInputPathButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        input_path = char();
        output_path = char();
        tform_SR_3D_488_405;
        tform_SR_3D_561_405;
        tform_SR_3D_488_561;
        tform_SR_3D_561_642;
        tform_SR_3D_488_642;
        tform_SR_2D_488_405;
        tform_SR_2D_561_405;
        tform_SR_2D_488_561;
        tform_SR_2D_561_642;
        tform_SR_2D_488_642;
        tform_confocal_3D_488_405;
        tform_confocal_3D_561_405;
        tform_confocal_3D_488_561;
        tform_confocal_3D_561_642;
        tform_confocal_3D_488_642;
        tform_confocal_2D_488_405;
        tform_confocal_2D_561_405;
        tform_confocal_2D_488_561;
        tform_confocal_2D_561_642;
        tform_confocal_2D_488_642;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DefineInputPathButton
        function DefineInputPathButtonPushed(app, event)
            app.input_path = [uigetdir('./'), filesep];
            disp(['Input path has been defined: ', app.input_path]);
            app.output_path = app.input_path;
        end

        % Button pushed function: DefineOutputPathButton
        function DefineOutputPathButtonPushed(app, event)
            app.output_path = uigetdir(app.input_path);
            disp(['Output path has been defined: ', app.output_path]);
        end

        % Button pushed function: RegistratorButton
        function RegistratorButtonPushed(app, event)
            
            if app.DropDown.Value == "Single file"
                [filename, filepath, ~] = uigetfile(...
                    [app.input_path, '*.nd2'], 'Select a nd2 file for registration ...');
                nd2_file_list = dir([filepath, filename]);
            elseif app.DropDown.Value == "Full directory"
                nd2_file_list = dir([app.input_path, '*.nd2']);
            end

            imaging_setting.mode = app.ImagingModeDropDown.Value;
            imaging_setting.dimension = app.DimensionDropDown.Value;

            for file_iter = 1:length(nd2_file_list)
                nd2_filename = nd2_file_list(file_iter).name;

                nd2_reader = bfGetReader([app.input_path, nd2_filename]);
                omeMeta = nd2_reader.getMetadataStore();
                
                sizeX = nd2_reader.getSizeX();
                sizeY = nd2_reader.getSizeY();
                sizeZ = nd2_reader.getSizeZ();
                sizeC = nd2_reader.getSizeC();
                sizeT = nd2_reader.getSizeT();

                if imaging_setting.dimension == "3D"
                
                    px = double(omeMeta.getPixelsPhysicalSizeX(0).value());
                    py = double(omeMeta.getPixelsPhysicalSizeY(0).value());
                    pz = double(omeMeta.getPixelsPhysicalSizeZ(0).value());
    
                    app.PixelsizeumEditField.Value = px;
                    app.ZstepsizeumEditField.Value = pz;
                    
                    disp(['Read image sequence: ', nd2_filename]);
                    fprintf("Image sequence size: X=%d, Y=%d, Z=%d, C=%d, T=%d\n", sizeX, sizeY, sizeZ, sizeC, sizeT);
                    fprintf("Pixel size: X=%.3f um, Y=%.3f um, Z=%.3f um\n", px, py, pz);

                else

                    px = double(omeMeta.getPixelsPhysicalSizeX(0).value());
                    py = double(omeMeta.getPixelsPhysicalSizeY(0).value());
    
                    app.PixelsizeumEditField.Value = px;
                    
                    disp(['Read image sequence: ', nd2_filename]);
                    fprintf("Image sequence size: X=%d, Y=%d, Z=%d, C=%d, T=%d\n", sizeX, sizeY, sizeZ, sizeC, sizeT);
                    fprintf("Pixel size: X=%.3f um, Y=%.3f um\n", px, py);

                end
                
                channel_names = cell(1, sizeC);
                for c = 1:sizeC
                    chName = string(omeMeta.getChannelName(0, c-1));
                    fprintf("Channel %d: %s\n", c, chName);
                    temp = char(chName);
                    switch temp((end-2):(end-1))
                        case "40"
                            channel_names{1, c} = "405";
                        case "48"
                            channel_names{1, c} = "488";
                        case "56"
                            channel_names{1, c} = "560";
                        case "64"
                            channel_names{1, c} = "640";
                    end
                end
                channel_names = string(channel_names);
                
                % read img sequence
                img_stack = zeros(sizeY, sizeX, sizeZ, sizeC, sizeT, 'uint16');
                for t = 1:sizeT
                    for z = 1:sizeZ
                        for c = 1:sizeC
                            index = nd2_reader.getIndex(z-1, c-1, t-1) + 1;
                            img_stack(:,:,z,c,t) = bfGetPlane(nd2_reader, index);
                        end
                    end
                end

                nd2_reader.close(); 
                
                %%
                img_stack_reg = img_stack;
                
                if imaging_setting.dimension == "3D"
                    sizeFixed  = [sizeY, sizeX, sizeZ];
                    Rfixed = imref3d(sizeFixed); 
                    S = diag([1/px, 1/py, 1/pz, 1]);
                
                    if imaging_setting.mode == "LiveSR"
                        T_px = S * app.tform_SR_3D_488_405.A * inv(S);
                        tform_488_405 = affine3d(T_px');
                        T_px = S * app.tform_SR_3D_561_405.A * inv(S);
                        tform_561_405 = affine3d(T_px');
                        T_px = S * app.tform_SR_3D_488_561.A * inv(S);
                        tform_488_561 = affine3d(T_px');
                        T_px = S * app.tform_SR_3D_488_642.A * inv(S);
                        tform_488_642 = affine3d(T_px'); 
                        T_px = S * app.tform_SR_3D_561_642.A * inv(S);
                        tform_561_642 = affine3d(T_px'); 
                    elseif imaging_setting.mode == "Confocal"
                        T_px = S * app.tform_confocal_3D_488_561.A * inv(S);
                        tform_488_561 = affine3d(T_px');
                        T_px = S * app.tform_confocal_3D_488_405.A * inv(S);
                        tform_488_405 = affine3d(T_px');
                        T_px = S * app.tform_confocal_3D_561_405.A * inv(S);
                        tform_561_405 = affine3d(T_px');
                        T_px = S * app.tform_confocal_3D_488_642.A * inv(S);
                        tform_488_642 = affine3d(T_px'); 
                        T_px = S * app.tform_confocal_3D_561_642.A * inv(S);
                        tform_561_642 = affine3d(T_px');
                    end
                
                elseif imaging_setting.dimension == "2D"
                    sizeFixed  = [sizeY, sizeX];
                    Rfixed = imref2d(sizeFixed); 
                    S = diag([1/px, 1/py, 1]);
                    if imaging_setting.mode == "LiveSR"
                        T_px = S * app.tform_SR_2D_488_561.A * inv(S);
                        tform_488_561 = affine2d(T_px');
                        T_px = S * app.tform_SR_2D_488_642.A * inv(S);
                        tform_488_642 = affine2d(T_px'); 
                        T_px = S * app.tform_SR_2D_561_642.A * inv(S);
                        tform_561_642 = affine2d(T_px'); 
                        T_px = S * app.tform_SR_2D_488_405.A * inv(S);
                        tform_488_405 = affine2d(T_px'); 
                        T_px = S * app.tform_SR_2D_561_405.A * inv(S);
                        tform_561_405 = affine2d(T_px'); 
                    elseif imaging_setting.mode == "Confocal"
                        T_px = S * app.tform_confocal_2D_488_561.A * inv(S);
                        tform_488_561 = affine2d(T_px');
                        T_px = S * app.tform_confocal_2D_488_642.A * inv(S);
                        tform_488_642 = affine2d(T_px'); 
                        T_px = S * app.tform_confocal_2D_561_642.A * inv(S);
                        tform_561_642 = affine2d(T_px'); 
                        T_px = S * app.tform_confocal_2D_488_405.A * inv(S);
                        tform_488_405 = affine2d(T_px'); 
                        T_px = S * app.tform_confocal_2D_561_405.A * inv(S);
                        tform_561_405 = affine2d(T_px'); 
                    end
                end
                
                if sizeC>1
                    if any(channel_names=="488")
                        % contain 488nm channels
                        if imaging_setting.dimension == "3D"
                            for t = 1:sizeT
                                for c = 1:sizeC
                                    temp_img_stack = img_stack(:, :, :, c, t);
                                    switch channel_names{1, c}
                                        case "405"
                                            movingReg = imwarp(temp_img_stack, tform_488_405, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                            img_stack_reg(:, :, :, c, t) = movingReg;
                                            % disp('correct 405 --> 488');
                                        case "488"
                                            img_stack_reg(:, :, :, c, t) = temp_img_stack;
                                        case "560"
                                            movingReg = imwarp(temp_img_stack, tform_488_561, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                            img_stack_reg(:, :, :, c, t) = movingReg;
                                            % disp('correct 560 --> 488');
                                        case "640"
                                            movingReg = imwarp(temp_img_stack, tform_488_642, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                            img_stack_reg(:, :, :, c, t) = movingReg;
                                            % disp('correct 640 --> 488');
                                    end
                                end
                            end
                        elseif imaging_setting.dimension == "2D"
                            for t = 1:sizeT
                                for c = 1:sizeC
                                    for z = 1:sizeZ
                                        temp_img_stack = img_stack(:, :, z, c, t);
                                        switch channel_names{1, c}
                                            case "405"
                                                % disp('correct 405 --> 488')
                                                movingReg = imwarp(temp_img_stack, tform_488_405, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                                img_stack_reg(:, :, z, c, t) = movingReg;
                                            case "488"
                                                img_stack_reg(:, :, z, c, t) = temp_img_stack;
                                            case "560"
                                                % disp('correct 560 --> 488')
                                                movingReg = imwarp(temp_img_stack, tform_488_561, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                                img_stack_reg(:, :, z, c, t) = movingReg;
                                            case "640"
                                                % disp('correct 640 --> 488')
                                                movingReg = imwarp(temp_img_stack, tform_488_642, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                                img_stack_reg(:, :, z, c, t) = movingReg;
                                        end
                                    end
                                end
                            end
                        end
                    elseif any(channel_names=="560")
                        % contain 560nm channels
                        if imaging_setting.dimension == "3D"
                            for t = 1:sizeT
                                for c = 1:sizeC
                                    temp_img_stack = img_stack(:, :, :, c, t);
                                    switch channel_names{1, c}
                                        case "405"
                                            movingReg = imwarp(temp_img_stack, tform_561_405, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                            img_stack_reg(:, :, :, c, t) = movingReg;
                                        case "560"
                                            img_stack_reg(:, :, :, c, t) = temp_img_stack;
                                        case "640"
                                            movingReg = imwarp(temp_img_stack, tform_561_642, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                            img_stack_reg(:, :, :, c, t) = movingReg;
                                    end
                                end
                            end
                        elseif imaging_setting.dimension == "2D"
                            for t = 1:sizeT
                                for c = 1:sizeC
                                    for z = 1:sizeZ
                                        temp_img_stack = img_stack(:, :, z, c, t);
                                        switch channel_names{1, c}
                                            case "405"
                                                movingReg = imwarp(temp_img_stack, tform_561_405, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                                img_stack_reg(:, :, z, c, t) = movingReg;
                                            case "560"
                                                img_stack_reg(:, :, z, c, t) = temp_img_stack;
                                            case "640"
                                                movingReg = imwarp(temp_img_stack, tform_561_642, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                                img_stack_reg(:, :, z, c, t) = movingReg;
                                        end
                                    end
                                end
                            end
                        end 
                    else
                        % only contain 405nm and 640nm channels
                        if imaging_setting.dimension == "3D"
                            for t = 1:sizeT
                                for c = 1:sizeC
                                    temp_img_stack = img_stack(:, :, :, c, t);
                                    switch channel_names{1, c}
                                        case "405"
                                            movingReg = imwarp(temp_img_stack, tform_488_405, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                            img_stack_reg(:, :, :, c, t) = movingReg;
                                        case "640"
                                            movingReg = imwarp(temp_img_stack, tform_488_642, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                            img_stack_reg(:, :, :, c, t) = movingReg;
                                    end
                                end
                            end
                        elseif imaging_setting.dimension == "2D"
                            for t = 1:sizeT
                                for c = 1:sizeC
                                    for z = 1:sizeZ
                                        temp_img_stack = img_stack(:, :, z, c, t);
                                        switch channel_names{1, c}
                                            case "405"
                                                movingReg = imwarp(temp_img_stack, tform_488_405, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                                img_stack_reg(:, :, z, c, t) = movingReg;
                                            case "640"
                                                movingReg = imwarp(temp_img_stack, tform_488_642, 'OutputView', Rfixed, 'InterpolationMethod', 'cubic', 'FillValues', 0);
                                                img_stack_reg(:, :, z, c, t) = movingReg;
                                        end
                                    end
                                end 
                            end
                        end 
                    end
                end
                
                %%
                bfsave(img_stack_reg, [app.output_path, filesep, nd2_filename(1:(end-4)), '.ome.tif']);
                disp(['Export corrected image sequence: ', nd2_filename]);
            end

            
        end

        % Button pushed function: LoadTransformMatrixButton
        function LoadTransformMatrixButtonPushed(app, event)
            [filename, filepath, ~] = uigetfile(...
                './*.mat', 'Select MAT file for registration ...');
            loadfile = load([filepath, filesep, filename]);
            disp(['Successfully load Transform Matix: ', filename]);
            app.tform_SR_3D_488_405 = loadfile.tform_SR_3D_488_405;
            app.tform_SR_3D_561_405 = loadfile.tform_SR_3D_561_405;
            app.tform_SR_3D_488_561 = loadfile.tform_SR_3D_488_561;
            app.tform_SR_3D_561_642 = loadfile.tform_SR_3D_561_642;
            app.tform_SR_3D_488_642 = loadfile.tform_SR_3D_488_642;
            app.tform_SR_2D_488_405 = loadfile.tform_SR_2D_488_405;
            app.tform_SR_2D_561_405 = loadfile.tform_SR_2D_561_405;
            app.tform_SR_2D_488_561 = loadfile.tform_SR_2D_488_561;
            app.tform_SR_2D_561_642 = loadfile.tform_SR_2D_561_642;
            app.tform_SR_2D_488_642 = loadfile.tform_SR_2D_488_642;
            app.tform_confocal_3D_488_405 = loadfile.tform_confocal_3D_488_405;
            app.tform_confocal_3D_561_405 = loadfile.tform_confocal_3D_561_405;
            app.tform_confocal_3D_488_561 = loadfile.tform_confocal_3D_488_561;
            app.tform_confocal_3D_561_642 = loadfile.tform_confocal_3D_561_642;
            app.tform_confocal_3D_488_642 = loadfile.tform_confocal_3D_488_642;
            app.tform_confocal_2D_488_405 = loadfile.tform_confocal_2D_488_405;
            app.tform_confocal_2D_561_405 = loadfile.tform_confocal_2D_561_405;
            app.tform_confocal_2D_488_561 = loadfile.tform_confocal_2D_488_561;
            app.tform_confocal_2D_561_642 = loadfile.tform_confocal_2D_561_642;
            app.tform_confocal_2D_488_642 = loadfile.tform_confocal_2D_488_642;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageRegistrationPanel = uipanel(app.UIFigure);
            app.ImageRegistrationPanel.Title = 'Image Registration';
            app.DefineInputPathButton = uibutton(app.ImageRegistrationPanel, 'push');
            app.DefineInputPathButton.ButtonPushedFcn = createCallbackFcn(app, @DefineInputPathButtonPushed, true);
            app.DefineInputPathButton.Text = 'Define Input Path';
            app.DefineOutputPathButton = uibutton(app.ImageRegistrationPanel, 'push');
            app.DefineOutputPathButton.ButtonPushedFcn = createCallbackFcn(app, @DefineOutputPathButtonPushed, true);
            app.DefineOutputPathButton.Text = 'Define Output Path';
            app.DimensionDropDownLabel = uilabel(app.ImageRegistrationPanel);
            app.DimensionDropDownLabel.Text = 'Dimension';
            app.DimensionDropDown = uidropdown(app.ImageRegistrationPanel);
            app.DimensionDropDown.Items = {'3D', '2D'};
            app.DimensionDropDown.Value = '3D';
            app.ImagingModeDropDownLabel = uilabel(app.ImageRegistrationPanel);
            app.ImagingModeDropDownLabel.Text = 'Imaging Mode';
            app.ImagingModeDropDown = uidropdown(app.ImageRegistrationPanel);
            app.ImagingModeDropDown.Items = {'LiveSR', 'Confocal'};
            app.ImagingModeDropDown.Value = 'LiveSR';
            app.PixelsizeumLabel = uilabel(app.ImageRegistrationPanel);
            app.PixelsizeumLabel.Text = {'Pixel size '; '(um) '};
            app.PixelsizeumEditField = uieditfield(app.ImageRegistrationPanel, 'numeric');
            app.PixelsizeumEditField.ValueDisplayFormat = '%.3f';
            app.PixelsizeumEditField.Editable = 'off';
            app.PixelsizeumEditField.Value = 0.0941;
            app.ZstepsizeumEditFieldLabel = uilabel(app.ImageRegistrationPanel);
            app.ZstepsizeumEditFieldLabel.Text = {'Z step size'; '(um) '};
            app.ZstepsizeumEditField = uieditfield(app.ImageRegistrationPanel, 'numeric');
            app.ZstepsizeumEditField.ValueDisplayFormat = '%.3f';
            app.ZstepsizeumEditField.Editable = 'off';
            app.ZstepsizeumEditField.Value = 0.1;
            app.RegistratorButton = uibutton(app.ImageRegistrationPanel, 'push');
            app.RegistratorButton.ButtonPushedFcn = createCallbackFcn(app, @RegistratorButtonPushed, true);
            app.RegistratorButton.Text = 'Registrator!';
            app.LoadTransformMatrixButton = uibutton(app.ImageRegistrationPanel, 'push');
            app.LoadTransformMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTransformMatrixButtonPushed, true);
            app.LoadTransformMatrixButton.Text = 'Load Transform Matrix';
            app.DropDown = uidropdown(app.ImageRegistrationPanel);
            app.DropDown.Items = {'Single file', 'Full directory'};
            app.DropDown.Value = 'Single file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageRegistrator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end